classdef MAE547DHParamsNoOffset < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        SubmitTableButton  matlab.ui.control.Button
        UITable            matlab.ui.control.Table
    end

    
    properties (Access = private)
        MainApp % Reference to mainapp XDDDDD
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, DHSize)
            value = DHSize;
            app.UITable.Data = cell(value,4);
            for i = 1:value
                for j = 1:4
                    app.UITable.Data{i, j} = '0';
                end
            end
            app.UITable.ColumnEditable = true(1,4);

            app.MainApp = mainapp;
        end

        % Button pushed function: SubmitTableButton
        function SubmitTableButtonPushed(app, event)
            SubmittedData = cellfun(@str2double,app.UITable.Data);
            setDHParams(app.MainApp,SubmittedData)
            app.MainApp.InitializeDHParametersButton.Enable = 'on';
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            app.MainApp.InitializeDHParametersButton.Enable = 'on';
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'a'; 'α'; 'd'; 'θ'};
            app.UITable.RowName = {};
            app.SubmitTableButton = uibutton(app.UIFigure, 'push');
            app.SubmitTableButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitTableButtonPushed, true);
            app.SubmitTableButton.Text = 'Submit Table';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MAE547DHParamsNoOffset(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end