classdef MAE547MainPage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        EOMButton                     matlab.ui.control.Button
        SimulationTimeEditField       matlab.ui.control.NumericEditField
        SimulationTimeEditFieldLabel  matlab.ui.control.Label
        GainScaleEditField            matlab.ui.control.NumericEditField
        GainScaleEditFieldLabel       matlab.ui.control.Label
        MdKnob                        matlab.ui.control.Knob
        MdKnobLabel                   matlab.ui.control.Label
        RoboticsSimulationApplicationLabel  matlab.ui.control.Label
        tau_zEditField                matlab.ui.control.EditField
        tau_zEditFieldLabel           matlab.ui.control.Label
        tau_yEditField                matlab.ui.control.EditField
        tau_yEditFieldLabel           matlab.ui.control.Label
        tau_xEditField                matlab.ui.control.EditField
        tau_xLabel                    matlab.ui.control.Label
        F_zEditField                  matlab.ui.control.EditField
        F_zEditFieldLabel             matlab.ui.control.Label
        F_yEditField                  matlab.ui.control.EditField
        F_yEditFieldLabel             matlab.ui.control.Label
        F_xEditField                  matlab.ui.control.EditField
        F_xEditFieldLabel             matlab.ui.control.Label
        KdKnob                        matlab.ui.control.Knob
        KdKnobLabel                   matlab.ui.control.Label
        KpKnob                        matlab.ui.control.Knob
        KpKnobLabel                   matlab.ui.control.Label
        ControlModeDropDown           matlab.ui.control.DropDown
        ControlModeDropDownLabel      matlab.ui.control.Label
        z_dEditField                  matlab.ui.control.EditField
        z_dEditFieldLabel             matlab.ui.control.Label
        y_dEditField                  matlab.ui.control.EditField
        y_dEditFieldLabel             matlab.ui.control.Label
        x_dEditField                  matlab.ui.control.EditField
        x_dLabel                      matlab.ui.control.Label
        DHSizeEditField               matlab.ui.control.NumericEditField
        DHSizeEditFieldLabel          matlab.ui.control.Label
        SIMULATEButton                matlab.ui.control.Button
        JointPositions                matlab.ui.container.TabGroup
        JointVariablesTab             matlab.ui.container.Tab
        JointAccels                   matlab.ui.control.UIAxes
        JointRates                    matlab.ui.control.UIAxes
        JointPosition                 matlab.ui.control.UIAxes
        EndEffectorPositionTab        matlab.ui.container.Tab
        EEPosition                    matlab.ui.control.UIAxes
        EndEffectorForcesTab          matlab.ui.container.Tab
        EEForces                      matlab.ui.control.UIAxes
        JointInputTorquesTab          matlab.ui.container.Tab
        JITorques                     matlab.ui.control.UIAxes
        AdditionalParametersButton    matlab.ui.control.Button
        InitializeDHParametersButton  matlab.ui.control.Button
        OffsetsCheckBox               matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        DHParamApp % Reference to DHParam Window :D
        NumericDHTable % DH Table with NAN
        Robot % the robot duh (rigidbodytree) 
        SymbolicDHTable % DHTable with q1 d1 XD
        EOM % eq of motion
        SetParamApp % Ref to SetParamWindow
        q0 % initial joints
        qd0 % Description
        simTime % Description
        Kd % Description
        g0 % Description
        mL % Description
        mm % Description
        Il % Description
        Im % Description
        kr % Description
        Fv % Description
        Fs % Description
        Kr % Gear ratio
        latexeom
        DHSize


        % Outputs
        t
        q
        qd
        qdd
        xe
        fe
        taue
    end
    
    methods (Access = public)
        
        function setDHParams(app,DHTable)
            app.NumericDHTable = DHTable;
            app.AdditionalParametersButton.Enable = "on";
            createSymbolicDH(app)

        end
        
        function SetParams(app,q0,qd0,g0,mL,mm,Il,Im,Fv,Fs,Kr)
            app.q0 = q0;
            app.qd0 = qd0;
            app.g0 = g0;
            app.mL = mL;
            app.mm = mm;
            app.Il = Il;
            app.Im = Im;
            app.Fv = Fv;
            app.Fs = Fs;
            app.Kr = Kr;
        end
    end
    
    methods (Access = private)
        
        function createSymbolicDH(app)
            app.SymbolicDHTable = sym([app.NumericDHTable zeros(size(app.NumericDHTable,1),1)]);
            lookup = ['d' 't' ];
            lookupcount = [0 0];
            for i = 1:2
                for j = 1:size(app.NumericDHTable,1)
                    if(isnan(app.NumericDHTable(j,i+2)))
                        lookupcount(i) = lookupcount(i) + 1;
                        app.SymbolicDHTable(j,i+2) = sym(lookup(i) + string(lookupcount(i)));
                        if(i==1)
                            app.SymbolicDHTable(j,5) = 1;
                        else
                            app.SymbolicDHTable(j,5) = 0;
                        end
                    end
                end
            end
            app.NumericDHTable = [app.NumericDHTable double(app.SymbolicDHTable(:,5))];
            app.NumericDHTable(isnan(app.NumericDHTable))=0;
            app.EOM = EOM(app.SymbolicDHTable);
            app.latexeom = eom2latex(app.EOM);


        end
        
        function PlotResults(app)
            plot(app.JointPosition,app.t,app.q);
            for i=1:app.DHSize
                lgd{i} = ['q_{' num2str(i) '}'];
            end
            legend(app.JointPosition,lgd)


            plot(app.JointRates,app.t,app.qd);
            legend(app.JointRates,lgd)

            plot(app.JointAccels,app.t,app.qdd)
            legend(app.JointAccels,lgd)

            plot(app.EEPosition,app.t,app.xe)
            legend(app.EEPosition,"X","Y","Z")

            plot(app.EEForces,app.t,app.fe)
            legend(app.EEForces,"F_{x}","F_{y}","F_{z}","\tau_{x}","\tau_{y}","\tau_{z}")

            plot(app.JITorques,app.t,app.taue)
            legend(app.JITorques,lgd)
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            startup_rvc;
            title(app.JointPosition,"Join Positions")
            xlabel(app.JointPosition,"Time (s)")
            ylabel(app.JointPosition,"Joint Value (rad)")

            title(app.JointRates,"Join Rates")
            xlabel(app.JointRates,"Time (s)")
            ylabel(app.JointRates,"Joint Rates (rad/s)")

            title(app.JointAccels,"Join Accelerations")
            xlabel(app.JointAccels,"Time (s)")
            ylabel(app.JointAccels,"Joint Acceleration (rad/s^2)")

            title(app.EEPosition,"End Effector Position")
            xlabel(app.EEPosition,"Time (s)")
            ylabel(app.EEPosition,"Position")

            title(app.EEForces,"End Effector Forces")
            xlabel(app.EEForces,"Time (s)")
            ylabel(app.EEForces,"Force")

            title(app.JITorques,"Joint Torques")
            xlabel(app.JITorques,"Time (s)")
            ylabel(app.JITorques,"Joint Torques")
        end

        % Button pushed function: InitializeDHParametersButton
        function InitializeDHParametersButtonPushed(app, event)
            app.DHSize = app.DHSizeEditField.Value;
            app.DHParamApp = MAE547DHParamsNoOffset(app,app.DHSize);
            app.InitializeDHParametersButton.Enable = 'off';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app.DHParamApp)
            delete(app.SetParamApp)
            delete(app)
        end

        % Button pushed function: AdditionalParametersButton
        function AdditionalParametersButtonPushed(app, event)
            app.SetParamApp = MAE547SetParams(app,app.DHSizeEditField.Value);
            app.AdditionalParametersButton.Enable = 'off';
        end

        % Value changed function: GainScaleEditField
        function GainScaleEditFieldValueChanged(app, event)
            value = app.GainScaleEditField.Value;
            app.KpKnob.Limits = [0 value];
            app.KdKnob.Limits = [0 value];
            app.MdKnob.Limits = [0 value];
        end

        % Button pushed function: SIMULATEButton
        function SIMULATEButtonPushed(app, event)
            syms time
            switch app.ControlModeDropDown.Value

                case "Impedance"
                    % do imp
                    disp(app.NumericDHTable)
                    xdes_sym = [eval(app.x_dEditField.Value); eval(app.y_dEditField.Value); eval(app.z_dEditField.Value)];
                    fdes_sym = [eval(app.F_xEditField.Value); eval(app.F_yEditField.Value); eval(app.F_zEditField.Value); eval(app.tau_xEditField.Value); eval(app.tau_yEditField.Value); eval(app.tau_zEditField.Value)];
                    [app.t,app.q,app.qd,app.qdd,app.xe,app.fe,app.taue] = foctrl_Imp(app.NumericDHTable,xdes_sym,fdes_sym,app.q0,app.qd0,app.SimulationTimeEditField.Value,app.KpKnob.Value,app.KdKnob.Value,app.MdKnob.Value,app.g0,app.mL',app.mm,app.Il,app.Im',app.Kr',app.Fv,app.Fs);
                    PlotResults(app);
                case "Compliance"
                    % do comp
                    xdes_sym = [eval(app.x_dEditField.Value); eval(app.y_dEditField.Value); eval(app.z_dEditField.Value)];
                    fdes_sym = [eval(app.F_xEditField.Value); eval(app.F_yEditField.Value); eval(app.F_zEditField.Value); eval(app.tau_xEditField.Value); eval(app.tau_yEditField.Value); eval(app.tau_zEditField.Value)];
                    [app.t,app.q,app.qd,app.qdd,app.xe,app.fe,app.taue] = foctrl_Comp(app.NumericDHTable,xdes_sym,fdes_sym,app.q0,app.qd0,app.SimulationTimeEditField.Value,app.KpKnob.Value,app.KdKnob.Value,app.g0,app.mL',app.mm,app.Il,app.Im',app.Kr',app.Fv,app.Fs);
                    PlotResults(app);
            end
        end

        % Button pushed function: EOMButton
        function EOMButtonPushed(app, event)
            fig = uifigure("Name","Equations of Motion");
            lbl = uilabel(fig);
            lbl.Text =  "$" + app.latexeom + "$";
            lbl.Interpreter = "latex";
            lbl.Position = [0 0 fig.Position(3) fig.Position(4)];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.OffsetsCheckBox = uicheckbox(app.UIFigure);
            app.OffsetsCheckBox.Text = 'Offsets';
            app.InitializeDHParametersButton = uibutton(app.UIFigure, 'push');
            app.InitializeDHParametersButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeDHParametersButtonPushed, true);
            app.InitializeDHParametersButton.Text = 'Initialize DH Parameters';
            app.AdditionalParametersButton = uibutton(app.UIFigure, 'push');
            app.AdditionalParametersButton.ButtonPushedFcn = createCallbackFcn(app, @AdditionalParametersButtonPushed, true);
            app.AdditionalParametersButton.Enable = 'off';
            app.AdditionalParametersButton.Text = 'Additional Parameters';
            app.JointPositions = uitabgroup(app.UIFigure);
            app.JointVariablesTab = uitab(app.JointPositions);
            app.JointVariablesTab.Title = 'Joint Variables';
            app.JointPosition = uiaxes(app.JointVariablesTab);
            title(app.JointPosition, 'Title')
            xlabel(app.JointPosition, 'X')
            ylabel(app.JointPosition, 'Y')
            zlabel(app.JointPosition, 'Z')
            app.JointRates = uiaxes(app.JointVariablesTab);
            title(app.JointRates, 'Title')
            xlabel(app.JointRates, 'X')
            ylabel(app.JointRates, 'Y')
            zlabel(app.JointRates, 'Z')
            app.JointAccels = uiaxes(app.JointVariablesTab);
            title(app.JointAccels, 'Title')
            xlabel(app.JointAccels, 'X')
            ylabel(app.JointAccels, 'Y')
            zlabel(app.JointAccels, 'Z')
            app.EndEffectorPositionTab = uitab(app.JointPositions);
            app.EndEffectorPositionTab.Title = 'End Effector Position';
            app.EEPosition = uiaxes(app.EndEffectorPositionTab);
            title(app.EEPosition, 'Title')
            xlabel(app.EEPosition, 'X')
            ylabel(app.EEPosition, 'Y')
            zlabel(app.EEPosition, 'Z')
            app.EndEffectorForcesTab = uitab(app.JointPositions);
            app.EndEffectorForcesTab.Title = 'End Effector Forces';
            app.EEForces = uiaxes(app.EndEffectorForcesTab);
            title(app.EEForces, 'Title')
            xlabel(app.EEForces, 'X')
            ylabel(app.EEForces, 'Y')
            zlabel(app.EEForces, 'Z')
            app.JointInputTorquesTab = uitab(app.JointPositions);
            app.JointInputTorquesTab.Title = 'Joint Input Torques';
            app.JITorques = uiaxes(app.JointInputTorquesTab);
            title(app.JITorques, 'Title')
            xlabel(app.JITorques, 'X')
            ylabel(app.JITorques, 'Y')
            zlabel(app.JITorques, 'Z')
            app.SIMULATEButton = uibutton(app.UIFigure, 'push');
            app.SIMULATEButton.ButtonPushedFcn = createCallbackFcn(app, @SIMULATEButtonPushed, true);
            app.SIMULATEButton.Text = 'SIMULATE';
            app.DHSizeEditFieldLabel = uilabel(app.UIFigure);
            app.DHSizeEditFieldLabel.Text = 'DH Size';
            app.DHSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.x_dLabel = uilabel(app.UIFigure);
            app.x_dLabel.Interpreter = 'latex';
            app.x_dLabel.Text = '$x_{d}$';
            app.x_dEditField = uieditfield(app.UIFigure, 'text');
            app.x_dEditField.Value = '2*exp(-time)';
            app.y_dEditFieldLabel = uilabel(app.UIFigure);
            app.y_dEditFieldLabel.Interpreter = 'latex';
            app.y_dEditFieldLabel.Text = '$y_{d}$';
            app.y_dEditField = uieditfield(app.UIFigure, 'text');
            app.y_dEditField.Value = '1*cos(pi*time/5)';
            app.z_dEditFieldLabel = uilabel(app.UIFigure);
            app.z_dEditFieldLabel.Interpreter = 'latex';
            app.z_dEditFieldLabel.Text = '$z_{d}$';
            app.z_dEditField = uieditfield(app.UIFigure, 'text');
            app.z_dEditField.Value = '0';
            app.ControlModeDropDownLabel = uilabel(app.UIFigure);
            app.ControlModeDropDownLabel.Text = 'Control Mode';
            app.ControlModeDropDown = uidropdown(app.UIFigure);
            app.ControlModeDropDown.Items = {'Impedance', 'Compliance'};
            app.ControlModeDropDown.Value = 'Impedance';
            app.KpKnobLabel = uilabel(app.UIFigure);
            app.KpKnobLabel.Text = 'Kp';
            app.KpKnob = uiknob(app.UIFigure, 'continuous');
            app.KpKnob.Value = 1;
            app.KdKnobLabel = uilabel(app.UIFigure);
            app.KdKnobLabel.Text = 'Kd';
            app.KdKnob = uiknob(app.UIFigure, 'continuous');
            app.KdKnob.Value = 1;
            app.F_xEditFieldLabel = uilabel(app.UIFigure);
            app.F_xEditFieldLabel.Interpreter = 'latex';
            app.F_xEditFieldLabel.Text = '$F_{x}$';
            app.F_xEditField = uieditfield(app.UIFigure, 'text');
            app.F_xEditField.Value = '10*sin(pi*time/2)';
            app.F_yEditFieldLabel = uilabel(app.UIFigure);
            app.F_yEditFieldLabel.Interpreter = 'latex';
            app.F_yEditFieldLabel.Text = '$F_{y}$';
            app.F_yEditField = uieditfield(app.UIFigure, 'text');
            app.F_yEditField.Value = '15*cos(pi*time/10)';
            app.F_zEditFieldLabel = uilabel(app.UIFigure);
            app.F_zEditFieldLabel.Interpreter = 'latex';
            app.F_zEditFieldLabel.Text = '$F_{z}$';
            app.F_zEditField = uieditfield(app.UIFigure, 'text');
            app.F_zEditField.Value = '0';
            app.tau_xLabel = uilabel(app.UIFigure);
            app.tau_xLabel.Interpreter = 'latex';
            app.tau_xLabel.Text = '$\tau_{x}$';
            app.tau_xEditField = uieditfield(app.UIFigure, 'text');
            app.tau_xEditField.Value = '0';
            app.tau_yEditFieldLabel = uilabel(app.UIFigure);
            app.tau_yEditFieldLabel.Interpreter = 'latex';
            app.tau_yEditFieldLabel.Text = '$\tau_{y}$';
            app.tau_yEditField = uieditfield(app.UIFigure, 'text');
            app.tau_yEditField.Value = '0';
            app.tau_zEditFieldLabel = uilabel(app.UIFigure);
            app.tau_zEditFieldLabel.Interpreter = 'latex';
            app.tau_zEditFieldLabel.Text = '$\tau_{z}$';
            app.tau_zEditField = uieditfield(app.UIFigure, 'text');
            app.tau_zEditField.Value = '0';
            app.RoboticsSimulationApplicationLabel = uilabel(app.UIFigure);
            app.RoboticsSimulationApplicationLabel.Text = 'Robotics Simulation Application';
            app.MdKnobLabel = uilabel(app.UIFigure);
            app.MdKnobLabel.Text = 'Md';
            app.MdKnob = uiknob(app.UIFigure, 'continuous');
            app.MdKnob.Value = 1;
            app.GainScaleEditFieldLabel = uilabel(app.UIFigure);
            app.GainScaleEditFieldLabel.Text = 'Gain Scale';
            app.GainScaleEditField = uieditfield(app.UIFigure, 'numeric');
            app.GainScaleEditField.ValueChangedFcn = createCallbackFcn(app, @GainScaleEditFieldValueChanged, true);
            app.GainScaleEditField.Value = 100;
            app.SimulationTimeEditFieldLabel = uilabel(app.UIFigure);
            app.SimulationTimeEditFieldLabel.Text = 'Simulation Time';
            app.SimulationTimeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SimulationTimeEditField.Value = 3;
            app.EOMButton = uibutton(app.UIFigure, 'push');
            app.EOMButton.ButtonPushedFcn = createCallbackFcn(app, @EOMButtonPushed, true);
            app.EOMButton.Text = 'EOM';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MAE547MainPage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end