classdef MAE547SetParams < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TabGroup                matlab.ui.container.TabGroup
        qq0Tab                  matlab.ui.container.Tab
        q0qd0                   matlab.ui.control.Table
        Label                   matlab.ui.control.Label
        g0Tab                   matlab.ui.container.Tab
        g0                      matlab.ui.control.Table
        g0isa3x1vectorofgravityaccelerationinbaseframeLabel  matlab.ui.control.Label
        mLmmTab                 matlab.ui.container.Tab
        mLmm                    matlab.ui.control.Table
        mLmmarenx1vectorsforthemassesoflinksandmotorsLabel  matlab.ui.control.Label
        ILTab                   matlab.ui.container.Tab
        Il                      matlab.ui.control.Table
        ILisa3nx3matrixfortheinertiatensorforeachlinkLabel  matlab.ui.control.Label
        ImTab                   matlab.ui.container.Tab
        Im                      matlab.ui.control.Table
        Imisannx1vectorforthemotorinertiasLabel  matlab.ui.control.Label
        FvFsTab                 matlab.ui.container.Tab
        FvFs                    matlab.ui.control.Table
        FvandFsarenx1vectorfortheviscousandcoulombfrictionLabel  matlab.ui.control.Label
        KrTab                   matlab.ui.container.Tab
        Kr                      matlab.ui.control.Table
        krisannx1vectorforthegearratiosLabel  matlab.ui.control.Label
        SubmitParametersButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % Reference to mainapp XDDDDD
        n % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, DHSize)
            app.n = DHSize;
            app.q0qd0.Data = zeros(app.n,2);
            app.q0qd0.ColumnEditable = true(1,2);

            app.mLmm.Data = zeros(app.n,2);
            app.mLmm.ColumnEditable = true(1,2);

            app.g0.Data = zeros(3,1);
            app.g0.ColumnEditable = true(1,1);

            app.Im.Data = zeros(app.n,1);
            app.Im.ColumnEditable = true(1,1);
           
            app.FvFs.Data = zeros(app.n,2);
            app.FvFs.ColumnEditable = true(1,2);
            
            app.Il.Data = zeros(app.n*3,3);
            app.Il.ColumnEditable = true(1,3);
            
            app.Kr.Data = ones(app.n,1);
            app.Kr.ColumnEditable = true(1,1);

            app.MainApp = mainapp;
        end

        % Button pushed function: SubmitParametersButton
        function SubmitParametersButtonPushed(app, event)
            q0 = app.q0qd0.Data(:,1);
            qd0 = app.q0qd0.Data(:,2);

            mL = app.mLmm.Data(:,1);
            mm = app.mLmm.Data(:,2);

            g0 = app.g0.Data;

            Im = app.Im.Data;

            Kr = app.Kr.Data;

            Fv = app.FvFs.Data(:,1);
            Fs = app.FvFs.Data(:,2);
            Il = zeros([3,3,app.n]);
            for i = 1:app.n
                ili = app.Il.Data(3*app.n-2:app.n*3,:);
                Il(:,:,i) = ili;
            end
            SetParams(app.MainApp,q0,qd0,g0,mL,mm,Il,Im,Fv,Fs,Kr);
            app.MainApp.AdditionalParametersButton.Enable = 'on';
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            app.MainApp.AdditionalParametersButton.Enable = 'on';
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.SubmitParametersButton = uibutton(app.UIFigure, 'push');
            app.SubmitParametersButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitParametersButtonPushed, true);
            app.SubmitParametersButton.Text = 'Submit Parameters';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.qq0Tab = uitab(app.TabGroup);
            app.qq0Tab.Title = 'q,q0';
            app.Label = uilabel(app.qq0Tab);
            app.Label.Text = 'q0 and qd0 are nx1 vectors for the inital joint positions and velocities';
            app.q0qd0 = uitable(app.qq0Tab);
            app.q0qd0.ColumnName = {'q0'; 'qd0'};
            app.q0qd0.RowName = {};
            app.g0Tab = uitab(app.TabGroup);
            app.g0Tab.Title = 'g0';
            app.g0isa3x1vectorofgravityaccelerationinbaseframeLabel = uilabel(app.g0Tab);
            app.g0isa3x1vectorofgravityaccelerationinbaseframeLabel.Text = 'g0 is a 3x1 vector of gravity acceleration in base frame';
            app.g0 = uitable(app.g0Tab);
            app.g0.ColumnName = {'g0'};
            app.g0.RowName = {};
            app.mLmmTab = uitab(app.TabGroup);
            app.mLmmTab.Title = 'mL,mm';
            app.mLmmarenx1vectorsforthemassesoflinksandmotorsLabel = uilabel(app.mLmmTab);
            app.mLmmarenx1vectorsforthemassesoflinksandmotorsLabel.Text = 'mL, mm are nx1 vectors for the masses of links and motors';
            app.mLmm = uitable(app.mLmmTab);
            app.mLmm.ColumnName = {'mL'; 'mm'};
            app.mLmm.RowName = {};
            app.ILTab = uitab(app.TabGroup);
            app.ILTab.Title = 'IL';
            app.ILisa3nx3matrixfortheinertiatensorforeachlinkLabel = uilabel(app.ILTab);
            app.ILisa3nx3matrixfortheinertiatensorforeachlinkLabel.Text = 'IL is a 3nx3 matrix for the inertia tensor for each link';
            app.Il = uitable(app.ILTab);
            app.Il.ColumnName = {'I*x'; 'I*y'; 'I*z'};
            app.Il.RowName = {};
            app.ImTab = uitab(app.TabGroup);
            app.ImTab.Title = 'Im';
            app.Imisannx1vectorforthemotorinertiasLabel = uilabel(app.ImTab);
            app.Imisannx1vectorforthemotorinertiasLabel.Text = 'Im is an nx1 vector for the motor inertias';
            app.Im = uitable(app.ImTab);
            app.Im.ColumnName = {'Im'};
            app.Im.RowName = {};
            app.FvFsTab = uitab(app.TabGroup);
            app.FvFsTab.Title = 'Fv, Fs';
            app.FvandFsarenx1vectorfortheviscousandcoulombfrictionLabel = uilabel(app.FvFsTab);
            app.FvandFsarenx1vectorfortheviscousandcoulombfrictionLabel.Text = 'Fv and Fs are nx1 vector for the viscous and coulomb friction';
            app.FvFs = uitable(app.FvFsTab);
            app.FvFs.ColumnName = {'Fv'; 'Fs'};
            app.FvFs.RowName = {};
            app.KrTab = uitab(app.TabGroup);
            app.KrTab.Title = 'Kr';
            app.krisannx1vectorforthegearratiosLabel = uilabel(app.KrTab);
            app.krisannx1vectorforthegearratiosLabel.Text = 'kr is an nx1 vector for the gear ratios';
            app.Kr = uitable(app.KrTab);
            app.Kr.ColumnName = {'Kr'};
            app.Kr.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MAE547SetParams(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end